classdef reactiontimeapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Continue           matlab.ui.control.TextArea
        Label              matlab.ui.control.Label
        ProgressLabel      matlab.ui.control.Label
        StartButton        matlab.ui.control.Button
        VisualStimulus     matlab.ui.container.Panel
        StimulusTypeLabel  matlab.ui.control.Label
        ReactionTimeLabel  matlab.ui.control.Label
    end

     properties (Access = private)
        startTime
        reactionTime
        currentStimulus
        requiredKeys
        keysPressed
        arduinoObj
        hapticPin
        trialList
        currentTrialIndex
        waitingForEnter = false
        timerObj
    end
    
    methods (Access = private)
        
        function startStimulus(app)
            app.keysPressed = {};
            app.startTime = tic;

            switch app.currentStimulus
                case 'V'
                    app.requiredKeys = {'v'};
                    app.StimulusTypeLabel.Text = 'V';
                    app.VisualStimulus.Visible = 'on';
                case 'A'
                    app.requiredKeys = {'a'};
                    app.StimulusTypeLabel.Text = 'A';
                    y = sin(1:500);
                    player = audioplayer(y, 44100);
                    play(player);
                case 'H'
                    app.requiredKeys = {'h'};
                    app.StimulusTypeLabel.Text = 'H';
                    if ~isempty(app.arduinoObj)
                        writeDigitalPin(app.arduinoObj, app.hapticPin, 1);
                        writeDigitalPin(app.arduinoObj, app.hapticPin, 0);
                    end
                case 'VA'
                    app.requiredKeys = {'v','a'};
                    app.StimulusTypeLabel.Text = 'VA';
                    app.VisualStimulus.Visible = 'on';
                    y = sin(1:500);
                    player = audioplayer(y, 44100);
                    play(player);
                case 'VH'
                    app.requiredKeys = {'v','h'};
                    app.StimulusTypeLabel.Text = 'VH';
                    app.VisualStimulus.Visible = 'on';
                    if ~isempty(app.arduinoObj)
                        writeDigitalPin(app.arduinoObj, app.hapticPin, 1);
                        writeDigitalPin(app.arduinoObj, app.hapticPin, 0);
                    end
                case 'AH'
                    app.requiredKeys = {'a','h'};
                    app.StimulusTypeLabel.Text = 'AH';
                    y = sin(1:500);
                    player = audioplayer(y, 44100);
                    play(player);
                    if ~isempty(app.arduinoObj)
                        writeDigitalPin(app.arduinoObj, app.hapticPin, 1);
                        writeDigitalPin(app.arduinoObj, app.hapticPin, 0);
                    end
                case 'VAH'
                    app.requiredKeys = {'v','a','h'};
                    app.StimulusTypeLabel.Text = 'VAH';
                    app.VisualStimulus.Visible = 'on';
                    y = sin(1:500);
                    player = audioplayer(y, 44100);
                    play(player);
                    if ~isempty(app.arduinoObj)
                        writeDigitalPin(app.arduinoObj, app.hapticPin, 1);
                        writeDigitalPin(app.arduinoObj, app.hapticPin, 0);
                    end
            end

            % Enable key listener
            app.UIFigure.WindowKeyPressFcn = @(src,event) app.keyPressed(event);

            drawnow;
            app.UIFigure.Visible = 'on';
            app.UIFigure.CurrentObject = app.UIFigure;
        end
        
        function keyPressed(app, event)
            key = lower(event.Key);

            % If waiting for Enter to continue
            if app.waitingForEnter
                if strcmp(key,'return') || strcmp(key,'enter')
                    app.currentTrialIndex = app.currentTrialIndex + 1;
                    app.startNextTrial();
                end
                return;
            end

            % Ignore invalid keys
            if ~ismember(key, {'v','a','h'})
                return
            end

            % Record unique key presses
            if ~ismember(key, app.keysPressed)
                app.keysPressed{end+1} = key;
            end

            % Record reaction time on first correct key
            if length(app.keysPressed) == 1
                app.reactionTime = toc(app.startTime);
            end

            % If all required keys pressed
            if all(ismember(app.requiredKeys, app.keysPressed))
                disp("all keys pressed")
                app.VisualStimulus.Visible = 'off';
                app.ReactionTimeLabel.Text = sprintf('Keys: %s | RT: %.3f s', ...
                    upper(strjoin(app.keysPressed, ',')), app.reactionTime);

                app.waitingForEnter = true;

                % Show built-in Continue text box
                app.Continue.Visible = 'on';
                app.Continue.Value = {'Press "Return" to Continue'};

                % Save trial data
                data = table(datetime("now"), ...
                    upper(strjoin(app.keysPressed, '+')), ...
                    app.reactionTime, ...
                    app.currentStimulus, ...
                    'VariableNames', {'Time','KeysPressed','ReactionTime','Stimulus'});
                if isfile('reaction_data.csv')
                    writetable(data,'reaction_data.csv','WriteMode','Append');
                else
                    writetable(data,'reaction_data.csv');
                end
            end
        end
        
        % --- Start next trial
        function startNextTrial(app)
            if app.currentTrialIndex > length(app.trialList)
                app.StimulusTypeLabel.Text = 'Experiment Complete!';
                app.ReactionTimeLabel.Text = '';
                app.VisualStimulus.Visible = 'off';
                app.ProgressLabel.Text = sprintf('Trial %d of %d', length(app.trialList), length(app.trialList));
                app.Continue.Visible = 'off';
                return;
            end

            app.waitingForEnter = false;
            app.VisualStimulus.Visible = 'off';
            app.Continue.Visible = 'off';

            app.currentStimulus = app.trialList{app.currentTrialIndex};
            app.ProgressLabel.Text = sprintf('Trial %d of %d', app.currentTrialIndex, length(app.trialList));
            app.StimulusTypeLabel.Text = 'Get Ready...';
            app.ReactionTimeLabel.Text = '';

            % Random delay using timer
            delay = 2 + rand()*3;
            if ~isempty(app.timerObj) && isvalid(app.timerObj)
                stop(app.timerObj); delete(app.timerObj);
            end
            app.timerObj = timer('StartDelay', delay, 'TimerFcn', @(~,~) startStimulus(app));
            start(app.timerObj);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Visible = 'off';
            app.VisualStimulus.Visible = 'off';
            app.Continue.Visible = 'off';
            app.StimulusTypeLabel.Text = 'Get Ready...';
            app.ReactionTimeLabel.Text = '';

            % Generate randomized trials (7 modes × 3 repetitions)
            modes = {'V','A','H','VA','VH','AH','VAH'};
            app.trialList = repmat(modes,1,3);
            app.trialList = app.trialList(randperm(length(app.trialList)));
            app.currentTrialIndex = 1;

            app.startNextTrial();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReactionTimeLabel = uilabel(app.UIFigure);
            app.ReactionTimeLabel.Text = 'Reaction Time:';
            app.StimulusTypeLabel = uilabel(app.UIFigure);
            app.StimulusTypeLabel.Text = 'Stimulus Type';
            app.VisualStimulus = uipanel(app.UIFigure);
            app.VisualStimulus.Visible = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ProgressLabel = uilabel(app.UIFigure);
            app.ProgressLabel.Text = 'Trial: 0/21';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Continue = uitextarea(app.UIFigure);
            app.Continue.Visible = 'off';
            app.Continue.Value = {''; 'Press "Return" to Continue'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reactiontimeapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end